classdef Experiment_Parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AuditoryStimulusPanel           matlab.ui.container.Panel
        DirectionsofMotiontoIncludeOnlyLRorRLworkcurrentlyLabel  matlab.ui.control.Label
        DtoUCheckBox                    matlab.ui.control.CheckBox
        UtoDCheckBox                    matlab.ui.control.CheckBox
        RtoLCheckBox                    matlab.ui.control.CheckBox
        LtoRCheckBox                    matlab.ui.control.CheckBox
        dBValue6087EditField            matlab.ui.control.NumericEditField
        dBValue6087EditFieldLabel       matlab.ui.control.Label
        InitialSilenceDurationsecEditField  matlab.ui.control.NumericEditField
        InitialSilenceDurationsecEditFieldLabel  matlab.ui.control.Label
        DurationsecEditField            matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel       matlab.ui.control.Label
        Coherence0010EditField_2        matlab.ui.control.NumericEditField
        Coherence0010EditField_2Label   matlab.ui.control.Label
        FixationStimulusPanel           matlab.ui.container.Panel
        RewardAngledegDropDown          matlab.ui.control.DropDown
        RewardAngledegDropDownLabel     matlab.ui.control.Label
        TargetAngledegDropDown          matlab.ui.control.DropDown
        TargetAngledegDropDownLabel     matlab.ui.control.Label
        CentralPositionsforFixationPanel  matlab.ui.container.Panel
        Button_9                        matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_1                        matlab.ui.control.StateButton
        ExperimentParametersPanel       matlab.ui.container.Panel
        DecisionTargetFixationTimemsEditField  matlab.ui.control.EditField
        DecisionTargetFixationTimemsEditFieldLabel  matlab.ui.control.Label
        FailureTimeoutmsEditField       matlab.ui.control.EditField
        FailureTimeoutmsEditFieldLabel  matlab.ui.control.Label
        FixationPointOnlyTimemsEditField  matlab.ui.control.NumericEditField
        FixationPointOnlyTimemsEditFieldLabel  matlab.ui.control.Label
        IntertrialIntervalmsEditField   matlab.ui.control.NumericEditField
        IntertrialIntervalmsEditFieldLabel  matlab.ui.control.Label
        RDKStimulusandFixationPointTimemsEditField  matlab.ui.control.NumericEditField
        RDKStimulusandFixationPointTimemsEditFieldLabel  matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.NumericEditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        RDKStimulusPanel                matlab.ui.container.Panel
        IncorrectTargetOpacity0010EditField  matlab.ui.control.NumericEditField
        IncorrectTargetOpacity0010EditFieldLabel  matlab.ui.control.Label
        DirectionsofMotiontoIncludeonlyLRworkLabel  matlab.ui.control.Label
        DOWNButton                      matlab.ui.control.StateButton
        LEFTButton                      matlab.ui.control.StateButton
        UPButton                        matlab.ui.control.StateButton
        RIGHTButton                     matlab.ui.control.StateButton
        DotVelocityEditField            matlab.ui.control.NumericEditField
        DotVelocityLabel                matlab.ui.control.Label
        Coherence0010EditField          matlab.ui.control.NumericEditField
        Coherence0010EditFieldLabel     matlab.ui.control.Label
        WindowAngledegDropDown          matlab.ui.control.DropDown
        WindowAngledegDropDownLabel     matlab.ui.control.Label
        ApplyParametersButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        data_cell = cell(33,1) ; % Cell for all of GUI data input to be written to .txt file
            
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TargetAngledegDropDown
        function TargetAngledegDropDownValueChanged(app, event)
            t_angle = app.TargetAngledegDropDown.Value;
            t_angle = str2double(t_angle);
            app.data_cell{1,1} = t_angle; 
        end

        % Value changed function: RewardAngledegDropDown
        function RewardAngledegDropDownValueChanged(app, event)
            w_angle = app.RewardAngledegDropDown.Value;
            w_angle = str2double(w_angle);
            app.data_cell{2,1} = w_angle;
        end

        % Button pushed function: ApplyParametersButton
        function ApplyParametersButtonPushed(app, event)

            writecell(app.data_cell,"data_cell.txt")
            close(app.UIFigure);
            disp("Data Written to File")
        end

        % Value changed function: NumberofTrialsEditField
        function NumberofTrialsEditFieldValueChanged(app, event)
            app.data_cell{3,1} = app.NumberofTrialsEditField.Value;
            
        end

        % Value changed function: 
        % RDKStimulusandFixationPointTimemsEditField
        function RDKStimulusandFixationPointTimemsEditFieldValueChanged(app, event)
            app.data_cell{4,1} = app.RDKStimulusandFixationPointTimemsEditField.Value;
            
        end

        % Value changed function: Button_1
        function Button_1_1ValueChanged(app, event)
            app.data_cell{7,1} = app.Button_1.Value;
            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
           app.data_cell{8,1} = app.Button_2.Value;
            
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            app.data_cell{9,1} = app.Button_3.Value;
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            app.data_cell{10,1} = app.Button_4.Value;
            
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            app.data_cell{11,1} = app.Button_5.Value;
            
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            app.data_cell{12,1} = app.Button_6.Value;
            
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            app.data_cell{13,1} = app.Button_7.Value;
            
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            app.data_cell{14,1} = app.Button_8.Value;
            
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            app.data_cell{15,1} = app.Button_9.Value;
            
        end

        % Value changed function: IntertrialIntervalmsEditField
        function IntertrialIntervalmsEditFieldValueChanged(app, event)
            app.data_cell{5,1} = app.IntertrialIntervalmsEditField.Value;
            
        end

        % Value changed function: FixationPointOnlyTimemsEditField
        function FixationPointOnlyTimemsEditFieldValueChanged(app, event)
            app.data_cell{6,1} = app.FixationPointOnlyTimemsEditField.Value;
            
        end

        % Value changed function: FailureTimeoutmsEditField
        function FailureTimeoutmsEditFieldValueChanged(app, event)
            app.data_cell{16,1} = app.FailureTimeoutmsEditField.Value;
            
        end

        % Value changed function: WindowAngledegDropDown
        function WindowAngledegDropDownValueChanged(app, event)
            rdk_angle = app.WindowAngledegDropDown.Value;
            rdk_angle = str2double(rdk_angle);
            app.data_cell{17,1} = rdk_angle;
        end

        % Value changed function: 
        % DecisionTargetFixationTimemsEditField
        function DecisionTargetFixationTimemsEditFieldValueChanged(app, event)
            app.data_cell{18,1} = app.DecisionTargetFixationTimemsEditField.Value;
        end

        % Value changed function: Coherence0010EditField
        function Coherence0010EditFieldValueChanged(app, event)
            app.data_cell{19,1} = app.Coherence0010EditField.Value;
            
        end

        % Value changed function: DotVelocityEditField
        function DotVelocityEditFieldValueChanged(app, event)
             app.data_cell{20,1} = app.DotVelocityEditField.Value;
            
        end

        % Value changed function: DOWNButton
        function DOWNButtonValueChanged(app, event)
            app.data_cell{21,1} = app.DOWNButton.Value;
            
        end

        % Value changed function: LEFTButton
        function LEFTButtonValueChanged(app, event)
            app.data_cell{22,1} = app.LEFTButton.Value;
            
        end

        % Value changed function: UPButton
        function UPButtonValueChanged(app, event)
            app.data_cell{23,1} = app.UPButton.Value;
            
        end

        % Value changed function: RIGHTButton
        function RIGHTButtonValueChanged(app, event)
            app.data_cell{24,1} = app.RIGHTButton.Value;
            
        end

        % Value changed function: 
        % IncorrectTargetOpacity0010EditField
        function IncorrectTargetOpacity0010EditFieldValueChanged(app, event)
            app.data_cell{25,1} = app.IncorrectTargetOpacity0010EditField.Value;
            
        end

        % Value changed function: Coherence0010EditField_2
        function Coherence0010EditField_2ValueChanged(app, event)
            app.data_cell{26,1} = app.Coherence0010EditField_2.Value;
            
        end

        % Value changed function: DurationsecEditField
        function DurationsecEditFieldValueChanged(app, event)
            app.data_cell{27,1} = app.DurationsecEditField.Value;
            
        end

        % Value changed function: InitialSilenceDurationsecEditField
        function InitialSilenceDurationsecEditFieldValueChanged(app, event)
            app.data_cell{28,1} = app.InitialSilenceDurationsecEditField.Value;
            
        end

        % Value changed function: dBValue6087EditField
        function dBValue6087EditFieldValueChanged(app, event)
            app.data_cell{29,1} = app.dBValue6087EditField.Value;
            
        end

        % Value changed function: LtoRCheckBox
        function LtoRCheckBoxValueChanged(app, event)
            app.data_cell{30,1} = app.LtoRCheckBox.Value;
            
        end

        % Value changed function: RtoLCheckBox
        function RtoLCheckBoxValueChanged(app, event)
            app.data_cell{31,1} = app.RtoLCheckBox.Value;
            
        end

        % Value changed function: UtoDCheckBox
        function UtoDCheckBoxValueChanged(app, event)
            app.data_cell{32,1} = app.UtoDCheckBox.Value;
            
        end

        % Value changed function: DtoUCheckBox
        function DtoUCheckBoxValueChanged(app, event)
            app.data_cell{33,1} = app.DtoUCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ApplyParametersButton = uibutton(app.UIFigure, 'push');
            app.ApplyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyParametersButtonPushed, true);
            app.ApplyParametersButton.Text = 'Apply Parameters';
            app.RDKStimulusPanel = uipanel(app.UIFigure);
            app.RDKStimulusPanel.Title = 'RDK Stimulus';
            app.WindowAngledegDropDownLabel = uilabel(app.RDKStimulusPanel);
            app.WindowAngledegDropDownLabel.Text = 'Window Angle (deg)';
            app.WindowAngledegDropDown = uidropdown(app.RDKStimulusPanel);
            app.WindowAngledegDropDown.Items = {'0', '25', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ItemsData = {'0', '25', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.WindowAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowAngledegDropDownValueChanged, true);
            app.WindowAngledegDropDown.Value = '0';
            app.Coherence0010EditFieldLabel = uilabel(app.RDKStimulusPanel);
            app.Coherence0010EditFieldLabel.Text = 'Coherence (0.0 - 1.0)';
            app.Coherence0010EditField = uieditfield(app.RDKStimulusPanel, 'numeric');
            app.Coherence0010EditField.ValueChangedFcn = createCallbackFcn(app, @Coherence0010EditFieldValueChanged, true);
            app.DotVelocityLabel = uilabel(app.RDKStimulusPanel);
            app.DotVelocityLabel.Text = 'Dot Velocity ';
            app.DotVelocityEditField = uieditfield(app.RDKStimulusPanel, 'numeric');
            app.DotVelocityEditField.ValueChangedFcn = createCallbackFcn(app, @DotVelocityEditFieldValueChanged, true);
            app.RIGHTButton = uibutton(app.RDKStimulusPanel, 'state');
            app.RIGHTButton.ValueChangedFcn = createCallbackFcn(app, @RIGHTButtonValueChanged, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.UPButton = uibutton(app.RDKStimulusPanel, 'state');
            app.UPButton.ValueChangedFcn = createCallbackFcn(app, @UPButtonValueChanged, true);
            app.UPButton.Text = 'UP';
            app.LEFTButton = uibutton(app.RDKStimulusPanel, 'state');
            app.LEFTButton.ValueChangedFcn = createCallbackFcn(app, @LEFTButtonValueChanged, true);
            app.LEFTButton.Text = 'LEFT';
            app.DOWNButton = uibutton(app.RDKStimulusPanel, 'state');
            app.DOWNButton.ValueChangedFcn = createCallbackFcn(app, @DOWNButtonValueChanged, true);
            app.DOWNButton.Text = 'DOWN';
            app.DirectionsofMotiontoIncludeonlyLRworkLabel = uilabel(app.RDKStimulusPanel);
            app.DirectionsofMotiontoIncludeonlyLRworkLabel.Text = 'Directions of Motion to Include (only L & R work)';
            app.IncorrectTargetOpacity0010EditFieldLabel = uilabel(app.RDKStimulusPanel);
            app.IncorrectTargetOpacity0010EditFieldLabel.Text = 'Incorrect Target Opacity (0.0-1.0)';
            app.IncorrectTargetOpacity0010EditField = uieditfield(app.RDKStimulusPanel, 'numeric');
            app.IncorrectTargetOpacity0010EditField.ValueChangedFcn = createCallbackFcn(app, @IncorrectTargetOpacity0010EditFieldValueChanged, true);
            app.ExperimentParametersPanel = uipanel(app.UIFigure);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.NumberofTrialsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.NumberofTrialsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofTrialsEditFieldValueChanged, true);
            app.RDKStimulusandFixationPointTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.RDKStimulusandFixationPointTimemsEditFieldLabel.Text = 'RDK  Stimulus and Fixation Point Time (ms)';
            app.RDKStimulusandFixationPointTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.RDKStimulusandFixationPointTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @RDKStimulusandFixationPointTimemsEditFieldValueChanged, true);
            app.IntertrialIntervalmsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.IntertrialIntervalmsEditFieldLabel.Text = 'Intertrial Interval (ms)';
            app.IntertrialIntervalmsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.IntertrialIntervalmsEditField.ValueChangedFcn = createCallbackFcn(app, @IntertrialIntervalmsEditFieldValueChanged, true);
            app.FixationPointOnlyTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.FixationPointOnlyTimemsEditFieldLabel.Text = 'Fixation Point Only Time (ms)';
            app.FixationPointOnlyTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.FixationPointOnlyTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @FixationPointOnlyTimemsEditFieldValueChanged, true);
            app.FailureTimeoutmsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.FailureTimeoutmsEditFieldLabel.Text = 'Failure Timeout (ms)';
            app.FailureTimeoutmsEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.FailureTimeoutmsEditField.ValueChangedFcn = createCallbackFcn(app, @FailureTimeoutmsEditFieldValueChanged, true);
            app.FailureTimeoutmsEditField.Value = '0';
            app.DecisionTargetFixationTimemsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.DecisionTargetFixationTimemsEditFieldLabel.Text = 'Decision Target FixationTime (ms)';
            app.DecisionTargetFixationTimemsEditField = uieditfield(app.ExperimentParametersPanel, 'text');
            app.DecisionTargetFixationTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @DecisionTargetFixationTimemsEditFieldValueChanged, true);
            app.DecisionTargetFixationTimemsEditField.Value = '0';
            app.CentralPositionsforFixationPanel = uipanel(app.UIFigure);
            app.CentralPositionsforFixationPanel.Title = 'Central Positions for Fixation ';
            app.Button_1 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button_1_1ValueChanged, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.CentralPositionsforFixationPanel, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '9';
            app.FixationStimulusPanel = uipanel(app.UIFigure);
            app.FixationStimulusPanel.Title = 'Fixation Stimulus';
            app.TargetAngledegDropDownLabel = uilabel(app.FixationStimulusPanel);
            app.TargetAngledegDropDownLabel.Text = 'Target Angle (deg)';
            app.TargetAngledegDropDown = uidropdown(app.FixationStimulusPanel);
            app.TargetAngledegDropDown.Items = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ItemsData = {'0', '.1', '.2', '.3', '.4', '.5', '.6', '.7', '.8', '.9', '1', '1.1', '1.2', '1.3', '1.4', '1.5', '1.6', '1.7', ''};
            app.TargetAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetAngledegDropDownValueChanged, true);
            app.TargetAngledegDropDown.Value = '0';
            app.RewardAngledegDropDownLabel = uilabel(app.FixationStimulusPanel);
            app.RewardAngledegDropDownLabel.Text = 'Reward Angle (deg)';
            app.RewardAngledegDropDown = uidropdown(app.FixationStimulusPanel);
            app.RewardAngledegDropDown.Items = {'0', '30', '27', '26', '25', '23', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.RewardAngledegDropDown.ItemsData = {'0', '30', '27', '26', '25', '23', '22', '20', '17', '15', '12.5', '10', '7.5', '5', '2.5'};
            app.RewardAngledegDropDown.ValueChangedFcn = createCallbackFcn(app, @RewardAngledegDropDownValueChanged, true);
            app.RewardAngledegDropDown.Value = '0';
            app.AuditoryStimulusPanel = uipanel(app.UIFigure);
            app.AuditoryStimulusPanel.Title = 'Auditory Stimulus ';
            app.Coherence0010EditField_2Label = uilabel(app.AuditoryStimulusPanel);
            app.Coherence0010EditField_2Label.Text = 'Coherence (0.0 -1.0)';
            app.Coherence0010EditField_2 = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.Coherence0010EditField_2.ValueChangedFcn = createCallbackFcn(app, @Coherence0010EditField_2ValueChanged, true);
            app.DurationsecEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.DurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsecEditFieldValueChanged, true);
            app.InitialSilenceDurationsecEditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.InitialSilenceDurationsecEditFieldLabel.Text = 'Initial Silence Duration (sec)';
            app.InitialSilenceDurationsecEditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.InitialSilenceDurationsecEditField.ValueChangedFcn = createCallbackFcn(app, @InitialSilenceDurationsecEditFieldValueChanged, true);
            app.dBValue6087EditFieldLabel = uilabel(app.AuditoryStimulusPanel);
            app.dBValue6087EditFieldLabel.Text = 'dB Value (60 - 87)';
            app.dBValue6087EditField = uieditfield(app.AuditoryStimulusPanel, 'numeric');
            app.dBValue6087EditField.ValueChangedFcn = createCallbackFcn(app, @dBValue6087EditFieldValueChanged, true);
            app.LtoRCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.LtoRCheckBox.ValueChangedFcn = createCallbackFcn(app, @LtoRCheckBoxValueChanged, true);
            app.LtoRCheckBox.Text = 'L to R';
            app.RtoLCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.RtoLCheckBox.ValueChangedFcn = createCallbackFcn(app, @RtoLCheckBoxValueChanged, true);
            app.RtoLCheckBox.Text = 'R to L';
            app.UtoDCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.UtoDCheckBox.ValueChangedFcn = createCallbackFcn(app, @UtoDCheckBoxValueChanged, true);
            app.UtoDCheckBox.Text = 'U to D';
            app.DtoUCheckBox = uicheckbox(app.AuditoryStimulusPanel);
            app.DtoUCheckBox.ValueChangedFcn = createCallbackFcn(app, @DtoUCheckBoxValueChanged, true);
            app.DtoUCheckBox.Text = 'D to U';
            app.DirectionsofMotiontoIncludeOnlyLRorRLworkcurrentlyLabel = uilabel(app.AuditoryStimulusPanel);
            app.DirectionsofMotiontoIncludeOnlyLRorRLworkcurrentlyLabel.Text = 'Directions of Motion to Include (Only LR or RL work currently)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Experiment_Parameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end